classdef phaseVocoderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ByOwenMacDonaldLabel            matlab.ui.control.Label
        PhaseVocoderLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PlayOutputSoundButton           matlab.ui.control.Button
        PlayOriginalSoundButton         matlab.ui.control.Button
        ProcessAudioButton              matlab.ui.control.Button
        LoadAudioButton                 matlab.ui.control.Button
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        PitchShiftsemitonesSlider       matlab.ui.control.Slider
        PitchShiftsemitonesSliderLabel  matlab.ui.control.Label
        TimeStretchSlider               matlab.ui.control.Slider
        TimeStretchSliderLabel          matlab.ui.control.Label
        OutputAxes                      matlab.ui.control.UIAxes
        InputAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        x; % Input signal
        fs; % Input signal sample rate
        y; % Output signal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: TimeStretchSlider
        function TimeStretchSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text = num2str(changingValue);
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.wav', 'Select audio file'); % Prompt file selection

            % Return if window is closed
            if fileName == 0
                return;
            end

            % Read selected input file
            [app.x, app.fs] = audioread(fullfile(filePath, fileName));
            
            % Scale to [-1, 1] range
            app.x = app.x / max(abs(app.x));

            % Plot input signal
            t = (0:length(app.x)-1) / app.fs;
            plot(app.InputAxes, t, app.x);
            app.InputAxes.XLim = [0, t(end)];
        end

        % Button pushed function: ProcessAudioButton
        function ProcessAudioButtonPushed(app, event)
            % Error message if no input signal is loaded
            if isempty(app.x)
                uialert(app.UIFigure, "No audio file loaded", "Error");
                return;
            end

            % Read parameter values
            tStretch = app.TimeStretchSlider.Value;
            pitchShift = app.PitchShiftsemitonesSlider.Value;
            winLen = app.WindowLengthEditField.Value;
            overlap = app.OverlapEditField.Value;

            % Call phase vocoder function to synthesize output
            app.y = phaseVocoder(app.x, tStretch, pitchShift, winLen, overlap);

            % Plot output signal
            t = (0:length(app.y)-1) / app.fs;
            plot(app.OutputAxes, t, app.y);
            app.OutputAxes.XLim = [0, t(end)];
        end

        % Button pushed function: PlayOriginalSoundButton
        function PlayOriginalSoundButtonPushed(app, event)
            % Error message if no input signal is loaded
            if isempty(app.x)
                uialert(app.UIFigure, "No original sound loaded", "Error");
                return;
            end

            % Play original sound
            soundsc(app.x, app.fs);
        end

        % Button pushed function: PlayOutputSoundButton
        function PlayOutputSoundButtonPushed(app, event)
            % Error message if no output signal is processed
            if isempty(app.y)
                uialert(app.UIFigure, "No output sound processed", "Error");
                return;
            end

            % Play original sound
            soundsc(app.y, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Input Signal')
            xlabel(app.InputAxes, 'Time (seconds)')
            ylabel(app.InputAxes, 'Amplitude')
            zlabel(app.InputAxes, 'Z')
            app.OutputAxes = uiaxes(app.UIFigure);
            title(app.OutputAxes, 'Output Signal')
            xlabel(app.OutputAxes, 'Time (seconds)')
            ylabel(app.OutputAxes, 'Amplitude')
            zlabel(app.OutputAxes, 'Z')
            app.TimeStretchSliderLabel = uilabel(app.UIFigure);
            app.TimeStretchSliderLabel.Text = 'Time Stretch';
            app.TimeStretchSlider = uislider(app.UIFigure);
            app.TimeStretchSlider.Limits = [0.5 3];
            app.TimeStretchSlider.MajorTicks = [0.5 1 1.5 2 2.5 3];
            app.TimeStretchSlider.ValueChangingFcn = createCallbackFcn(app, @TimeStretchSliderValueChanging, true);
            app.TimeStretchSlider.Step = 0.25;
            app.TimeStretchSlider.Value = 1;
            app.PitchShiftsemitonesSliderLabel = uilabel(app.UIFigure);
            app.PitchShiftsemitonesSliderLabel.Text = {'Pitch Shift'; '(semitones)'};
            app.PitchShiftsemitonesSlider = uislider(app.UIFigure);
            app.PitchShiftsemitonesSlider.Limits = [-12 12];
            app.PitchShiftsemitonesSlider.MinorTicks = [-11 -10 -8 -7 -5 -4 -2 -1 1 2 4 5 7 8 10 11];
            app.PitchShiftsemitonesSlider.Step = 1;
            app.OverlapEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapEditFieldLabel.Text = 'Overlap %';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditField.Value = 0.875;
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthEditField.Value = 2048;
            app.LoadAudioButton = uibutton(app.UIFigure, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.ProcessAudioButton = uibutton(app.UIFigure, 'push');
            app.ProcessAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAudioButtonPushed, true);
            app.ProcessAudioButton.Text = 'Process Audio';
            app.PlayOriginalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSoundButtonPushed, true);
            app.PlayOriginalSoundButton.Text = 'Play Original Sound';
            app.PlayOutputSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOutputSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputSoundButtonPushed, true);
            app.PlayOutputSoundButton.Text = 'Play Output Sound';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.PhaseVocoderLabel = uilabel(app.UIFigure);
            app.PhaseVocoderLabel.Text = 'Phase Vocoder';
            app.ByOwenMacDonaldLabel = uilabel(app.UIFigure);
            app.ByOwenMacDonaldLabel.Text = 'By Owen MacDonald';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phaseVocoderApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end